classdef ImageProcessingAnalysisSystem < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TitleLabelUIFigure     matlab.ui.Figure
        QualityButtonPushed_2  matlab.ui.control.Button
        YOLOButton             matlab.ui.control.Button
        ObjectDetectionButton  matlab.ui.control.Button
        CannyButton            matlab.ui.control.Button
        PrewittButton          matlab.ui.control.Button
        SobelButton            matlab.ui.control.Button
        DenoiseButton          matlab.ui.control.Button
        HistogramButton        matlab.ui.control.Button
        EnhancementButton      matlab.ui.control.Button
        GrayscaleButton        matlab.ui.control.Button
        LoadImageButton        matlab.ui.control.Button
        IMAGEPROCESSINGANALYSISSYSTEMLabel  matlab.ui.control.Label
        ProcessedAxes          matlab.ui.control.UIAxes
        OriginalAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        I
      end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
    [file, path] = uigetfile( ...
        {'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files'}, ...
        'Select an Image');

    if isequal(file, 0)
        return;
    end

    app.I = imread(fullfile(path, file));

    imshow(app.I, 'Parent', app.OriginalAxes);

    title(app.OriginalAxes, 'Original Image');
        end

        % Button down function: ProcessedAxes
        function ProcessedAxesButtonDown(app, event)
            
        end

        % Button pushed function: GrayscaleButton
        function GrayscaleButtonPushed(app, event)
           if isempty(app.I)
    uialert(app.UIFigure, ...
        'Please load an image first.', ...
        'No Image');
    return;
end

if size(app.I, 3) == 3
    grayImage = rgb2gray(app.I);
else
    grayImage = app.I;
end

imshow(grayImage, 'Parent', app.ProcessedAxes);

title(app.ProcessedAxes, 'Grayscale Image'); 
        end

        % Button pushed function: EnhancementButton
        function EnhancementButtonPushed(app, event)
            if isempty(app.I)
    uialert(app.UIFigure, ...
        'Please load an image first.', ...
        'No Image');
    return;
end

% Convert to grayscale
if size(app.I, 3) == 3
    grayImage = rgb2gray(app.I);
else
    grayImage = app.I;
end

% Contrast enhancement
enhancedImage = imadjust(grayImage);

% Display enhanced image
imshow(enhancedImage, 'Parent', app.ProcessedAxes);

title(app.ProcessedAxes, 'Contrast Enhanced Image');
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            if isempty(app.I)
    uialert(app.UIFigure, ...
        'Please load an image first.', ...
        'No Image');
    return;
end

% Convert to grayscale
if size(app.I, 3) == 3
    grayImage = rgb2gray(app.I);
else
    grayImage = app.I;
end

% Histogram equalization
equalizedImage = histeq(grayImage);

% Display result
imshow(equalizedImage, 'Parent', app.ProcessedAxes);

title(app.ProcessedAxes, 'Histogram Equalized Image');
        end

        % Button pushed function: DenoiseButton
        function DenoiseButtonPushed(app, event)
            if isempty(app.I)
    uialert(app.UIFigure, ...
        'Please load an image first.', ...
        'No Image');
    return;
end

% Convert to grayscale
if size(app.I, 3) == 3
    grayImage = rgb2gray(app.I);
else
    grayImage = app.I;
end

% Add salt and pepper noise
noisyImage = imnoise(grayImage, 'salt & pepper', 0.05);

% Remove noise using median filter
denoisedImage = medfilt2(noisyImage, [3 3]);

% Display denoised image
imshow(denoisedImage, 'Parent', app.ProcessedAxes);

title(app.ProcessedAxes, 'Denoised Image');
        end

        % Callback function
        function SobelButtonPushed(app, event)
           
    if isempty(app.I)
        uialert(app.UIFigure, ...
            'Please load an image first.', ...
            'No Image');
        return;
    end

    % Convert to grayscale
    if size(app.I, 3) == 3
        grayImage = rgb2gray(app.I);
    else
        grayImage = app.I;
    end

    % Sobel edge detection
    sobelEdges = edge(grayImage, 'Sobel');

    % Display result
    imshow(sobelEdges, 'Parent', app.ProcessedAxes);

    title(app.ProcessedAxes, 'Sobel Edge Detection');


        end

        % Button pushed function: SobelButton
        function SobelButtonPushed3(app, event)
            

    if isempty(app.I)
        uialert(app.UIFigure, ...
            'Please load an image first.', ...
            'No Image');
        return;
    end

    % Convert to grayscale
    if size(app.I, 3) == 3
        grayImage = rgb2gray(app.I);
    else
        grayImage = app.I;
    end

    % Sobel edge detection
    sobelEdges = edge(grayImage, 'Sobel');

    % Display result
    imshow(sobelEdges, 'Parent', app.ProcessedAxes);

    title(app.ProcessedAxes, 'Sobel Edge Detection');


        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)


    if isempty(app.I)
        uialert(app.UIFigure, ...
            'Please load an image first.', ...
            'No Image');
        return;
    end

    % Convert to grayscale
    if size(app.I, 3) == 3
        grayImage = rgb2gray(app.I);
    else
        grayImage = app.I;
    end

    % Prewitt edge detection
    prewittEdges = edge(grayImage, 'Prewitt');

    % Display result
    imshow(prewittEdges, 'Parent', app.ProcessedAxes);

    title(app.ProcessedAxes, 'Prewitt Edge Detection');
        end

        % Button pushed function: CannyButton
        function CannyButtonPushed(app, event)


    if isempty(app.I)
        uialert(app.UIFigure, ...
            'Please load an image first.', ...
            'No Image');
        return;
    end

    % Convert to grayscale
    if size(app.I, 3) == 3
        grayImage = rgb2gray(app.I);
    else
        grayImage = app.I;
    end

    % Canny edge detection
    cannyEdges = edge(grayImage, 'Canny');

    % Display result
    imshow(cannyEdges, 'Parent', app.ProcessedAxes);

    title(app.ProcessedAxes, 'Canny Edge Detection');


        end

        % Button pushed function: ObjectDetectionButton
        function ObjectDetectionButtonPushed(app, event)


    if isempty(app.I)
        uialert(app.UIFigure, ...
            'Please load an image first.', ...
            'No Image');
        return;
    end

    % Convert image to grayscale
    if size(app.I, 3) == 3
        grayImage = rgb2gray(app.I);
    else
        grayImage = app.I;
    end

    % Convert to binary image
    binaryImage = imbinarize(grayImage);

    % Invert if the background is mostly white
    if mean(binaryImage(:)) > 0.5
        binaryImage = ~binaryImage;
    end

    % Remove small regions
    binaryImage = bwareaopen(binaryImage, 50);

    % Find connected regions
    components = bwconncomp(binaryImage);
    stats = regionprops(components, 'BoundingBox', 'Area');

    % Display original image
    imshow(app.I, 'Parent', app.ProcessedAxes);
    hold(app.ProcessedAxes, 'on');

    % Draw boxes around detected regions
    count = 0;

    for k = 1:numel(stats)
        if stats(k).Area > 100
            rectangle(app.ProcessedAxes, ...
                'Position', stats(k).BoundingBox, ...
                'EdgeColor', 'r', ...
                'LineWidth', 2);
            count = count + 1;
        end
    end

    hold(app.ProcessedAxes, 'off');

    title(app.ProcessedAxes, ...
        sprintf('Detected Regions: %d', count));


        end

        % Button pushed function: YOLOButton
        function YOLOButtonPushed(app, event)
            

    if isempty(app.I)
        uialert(app.UIFigure, ...
            'Please load an image first.', ...
            'No Image');
        return;
    end

    try
        % Load pretrained YOLO detector
        detector = yolov4ObjectDetector("tiny-yolov4-coco");

        % Detect objects
        [bboxes, scores, labels] = detect(detector, app.I);

        % Display image
        imshow(app.I, 'Parent', app.ProcessedAxes);
        hold(app.ProcessedAxes, 'on');

        % Draw detection results
        if ~isempty(bboxes)
            labelText = cellstr(string(labels) + ...
                " (" + compose("%.2f", scores) + ")");

            for k = 1:size(bboxes, 1)
                rectangle(app.ProcessedAxes, ...
                    'Position', bboxes(k, :), ...
                    'EdgeColor', 'g', ...
                    'LineWidth', 2);
            end

            text(app.ProcessedAxes, ...
                bboxes(:, 1), bboxes(:, 2), labelText, ...
                'Color', 'yellow', ...
                'FontSize', 10, ...
                'FontWeight', 'bold', ...
                'VerticalAlignment', 'bottom');
        end

        hold(app.ProcessedAxes, 'off');
        title(app.ProcessedAxes, ...
            sprintf('YOLO: %d Objects Detected', size(bboxes, 1)));

    catch ME
        uialert(app.UIFigure, ME.message, ...
            'YOLO Detection Error');
    end


        end

        % Button pushed function: QualityButtonPushed_2
        function QualityButtonPushed_2Pushed(app, event)
            
function QualityButtonPushed2(app, event)

    % Check whether an image is loaded
    if isempty(app.I)
        disp('Please load an image first.');
        return;
    end

    try
        % Convert original image to grayscale
        if size(app.I, 3) == 3
            grayImage = rgb2gray(app.I);
        else
            grayImage = app.I;
        end

        % Original image
        originalImage = im2double(grayImage);

        % Start processing timer
        tic;

        % Enhance image contrast
        enhancedImage = imadjust(grayImage);

        processingTime = toc;

        % Calculate quality metrics
        enhancedDouble = im2double(enhancedImage);

        mseValue = immse(originalImage, enhancedDouble);
        psnrValue = psnr(originalImage, enhancedDouble);
        ssimValue = ssim(originalImage, enhancedDouble);

        % Display enhanced image
        imshow(enhancedImage, 'Parent', app.ProcessedAxes);
        title(app.ProcessedAxes, 'Enhanced Image');

        % Display results in Command Window
        fprintf('\n=== IMAGE QUALITY ANALYSIS ===\n');
        fprintf('MSE: %.6f\n', mseValue);
        fprintf('PSNR: %.4f dB\n', psnrValue);
        fprintf('SSIM: %.4f\n', ssimValue);
        fprintf('Processing Time: %.6f seconds\n', ...
            processingTime);

    catch ME
        fprintf('Quality Analysis Error: %s\n', ME.message);
    end

end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TitleLabelUIFigure = uifigure('Visible', 'off');
            app.TitleLabelUIFigure.Name = 'TitleLabel';
            app.OriginalAxes = uiaxes(app.TitleLabelUIFigure);
            title(app.OriginalAxes, 'OriginalAxes')
            xlabel(app.OriginalAxes, 'X')
            ylabel(app.OriginalAxes, 'Y')
            zlabel(app.OriginalAxes, 'Z')
            app.ProcessedAxes = uiaxes(app.TitleLabelUIFigure);
            title(app.ProcessedAxes, 'ProcessedAxes')
            xlabel(app.ProcessedAxes, 'X')
            ylabel(app.ProcessedAxes, 'Y')
            zlabel(app.ProcessedAxes, 'Z')
            app.ProcessedAxes.ButtonDownFcn = createCallbackFcn(app, @ProcessedAxesButtonDown, true);
            app.IMAGEPROCESSINGANALYSISSYSTEMLabel = uilabel(app.TitleLabelUIFigure);
            app.IMAGEPROCESSINGANALYSISSYSTEMLabel.Tag = 'TitleLabel';
            app.IMAGEPROCESSINGANALYSISSYSTEMLabel.Text = 'IMAGE PROCESSING & ANALYSIS SYSTEM';
            app.LoadImageButton = uibutton(app.TitleLabelUIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'LoadImageButton';
            app.GrayscaleButton = uibutton(app.TitleLabelUIFigure, 'push');
            app.GrayscaleButton.ButtonPushedFcn = createCallbackFcn(app, @GrayscaleButtonPushed, true);
            app.GrayscaleButton.Text = 'Grayscale';
            app.EnhancementButton = uibutton(app.TitleLabelUIFigure, 'push');
            app.EnhancementButton.ButtonPushedFcn = createCallbackFcn(app, @EnhancementButtonPushed, true);
            app.EnhancementButton.Text = 'Enhancement';
            app.HistogramButton = uibutton(app.TitleLabelUIFigure, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'HistogramButton';
            app.DenoiseButton = uibutton(app.TitleLabelUIFigure, 'push');
            app.DenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @DenoiseButtonPushed, true);
            app.DenoiseButton.Text = 'DenoiseButton';
            app.SobelButton = uibutton(app.TitleLabelUIFigure, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed3, true);
            app.SobelButton.Text = 'SobelButton';
            app.PrewittButton = uibutton(app.TitleLabelUIFigure, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'PrewittButton';
            app.CannyButton = uibutton(app.TitleLabelUIFigure, 'push');
            app.CannyButton.ButtonPushedFcn = createCallbackFcn(app, @CannyButtonPushed, true);
            app.CannyButton.Text = 'CannyButton';
            app.ObjectDetectionButton = uibutton(app.TitleLabelUIFigure, 'push');
            app.ObjectDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @ObjectDetectionButtonPushed, true);
            app.ObjectDetectionButton.Text = 'Object Detection';
            app.YOLOButton = uibutton(app.TitleLabelUIFigure, 'push');
            app.YOLOButton.ButtonPushedFcn = createCallbackFcn(app, @YOLOButtonPushed, true);
            app.YOLOButton.Text = 'YOLOButton';
            app.QualityButtonPushed_2 = uibutton(app.TitleLabelUIFigure, 'push');
            app.QualityButtonPushed_2.ButtonPushedFcn = createCallbackFcn(app, @QualityButtonPushed_2Pushed, true);
            app.QualityButtonPushed_2.Text = 'QualityButtonPushed';
            % Show the figure after all components are created
            app.TitleLabelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageProcessingAnalysisSystem

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TitleLabelUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TitleLabelUIFigure)
        end
    end
end